classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                            matlab.ui.Figure
        hozonnButton                    matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        modeChangeGroup                 matlab.ui.container.ButtonGroup
        Demomode                        matlab.ui.control.RadioButton
        Defaultmode                     matlab.ui.control.RadioButton
        RebootButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        importedfileField               matlab.ui.control.EditField
        importButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalibartionTab                  matlab.ui.container.Tab
        CalibrationOKButton             matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        cutAtField                      matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        maxDistField                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        MarkerSizeField                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        RotManual                       matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        RotationButton                  matlab.ui.control.Button
        zRotField                       matlab.ui.control.NumericEditField
        yRotField                       matlab.ui.control.NumericEditField
        xRotField                       matlab.ui.control.NumericEditField
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        XSlider                         matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        CalibrationResultPanel          matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        rateField                       matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        MarkerDiaField                  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        CalMarkerRate                   matlab.ui.control.Button
        cutMarkerBtn                    matlab.ui.control.Button
        regionSetBtn                    matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        UIAxes6_zx                      matlab.ui.control.UIAxes
        UIAxes6_xy                      matlab.ui.control.UIAxes
        UIAxes6_yz                      matlab.ui.control.UIAxes
        PostureTab                      matlab.ui.container.Tab
        ManualPosturePanel              matlab.ui.container.Panel
        RotOnPostureSectionPanel        matlab.ui.container.Panel
        XSlider_3                       matlab.ui.control.Slider
        XSlider_3Label                  matlab.ui.control.Label
        YSlider_3                       matlab.ui.control.Slider
        YSlider_3Label                  matlab.ui.control.Label
        ManualRotationBtn               matlab.ui.control.Button
        ManualxRotField                 matlab.ui.control.NumericEditField
        ManualyRotField                 matlab.ui.control.NumericEditField
        ManualzRotField                 matlab.ui.control.NumericEditField
        ZSlider_3                       matlab.ui.control.Slider
        ZSlider_2Label                  matlab.ui.control.Label
        ManualPosOKButton               matlab.ui.control.Button
        DataSelection                   matlab.ui.container.ButtonGroup
        LegDirAlreadyFixed              matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        Original                        matlab.ui.control.RadioButton
        PostureControlPanel             matlab.ui.container.Panel
        backpreviousBtn                 matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        HoofTipLength                   matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
        Label_39                        matlab.ui.control.Label
        iffixHoofDirSwitch              matlab.ui.control.ToggleSwitch
        Slider                          matlab.ui.control.Slider
        Label_15                        matlab.ui.control.Label
        fixLegAndHoofTipDirectionBtn    matlab.ui.control.Button
        modelUpdateBtn                  matlab.ui.control.Button
        CtrZBtn                         matlab.ui.control.Button
        downSlider                      matlab.ui.control.Slider
        DownLabel                       matlab.ui.control.Label
        upperSlider                     matlab.ui.control.Slider
        UPLabel                         matlab.ui.control.Label
        TrimmerDownField                matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        TrimmerUpField                  matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        DoneTrimBtn                     matlab.ui.control.Button
        postureOKBtn                    matlab.ui.control.Button
        ResetPostureButton              matlab.ui.control.Button
        TrimDirectionSelectButtonGroup  matlab.ui.container.ButtonGroup
        zButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        xButton                         matlab.ui.control.RadioButton
        PCATrimmerAppButton             matlab.ui.control.Button
        AutoOrManual                    matlab.ui.container.ButtonGroup
        Manual                          matlab.ui.control.RadioButton
        Auto                            matlab.ui.control.RadioButton
        CalculateTab                    matlab.ui.container.Tab
        CalculationDirectionAxes        matlab.ui.container.ButtonGroup
        zButton_2                       matlab.ui.control.RadioButton
        yButton_2                       matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        ManualCalPanel                  matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        CalculationManualy              matlab.ui.control.Button
        OnePointSelection               matlab.ui.container.Panel
        getPositionBtn                  matlab.ui.control.Button
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ReChooseBtn                     matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        CalculationManualy_2            matlab.ui.control.Button
        ROISelectionPanel               matlab.ui.container.Panel
        ResetCalROIBtn                  matlab.ui.control.Button
        ClipingWithROI                  matlab.ui.control.Button
        ROIButton_2                     matlab.ui.control.Button
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        CircumSearchTabGroup            matlab.ui.container.TabGroup
        csAreaTab                       matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes_csArea                   matlab.ui.control.UIAxes
        UIAxes_csArea_diff1             matlab.ui.control.UIAxes
        UIAxes_csArea_diff2             matlab.ui.control.UIAxes
        boundRectangleTab               matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes_recArea                  matlab.ui.control.UIAxes
        UIAxes_recArea_diff1            matlab.ui.control.UIAxes
        UIAxes_recArea_diff2            matlab.ui.control.UIAxes
        CircleFitTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes_rho                      matlab.ui.control.UIAxes
        UIAxes_rho_diff1                matlab.ui.control.UIAxes
        UIAxes_rho_diff2                matlab.ui.control.UIAxes
        resultApp                       matlab.ui.container.Panel
        boundRecAreaPanel               matlab.ui.container.Panel
        recwithMarkCircum2Field         matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        recCircum2Field                 matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        recCircumMarkField              matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        recCircumLength                 matlab.ui.control.NumericEditField
        Label_16                        matlab.ui.control.Label
        CircleFitRhoPanel               matlab.ui.container.Panel
        rhowithMarkCircum2Field         matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        rhoCircum2Field                 matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        rhoCircumMarkField              matlab.ui.control.NumericEditField
        Label_21                        matlab.ui.control.Label
        rhoCircumLength                 matlab.ui.control.NumericEditField
        Label_20                        matlab.ui.control.Label
        convAreaPanel                   matlab.ui.container.Panel
        withMarkCircum2Field            matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        Circum2Field                    matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        calCircumMarkField              matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        convCircumLength                matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        ToSaveDataTabBtn                matlab.ui.control.Button
        AutoOrManualCal                 matlab.ui.container.ButtonGroup
        ROIselectionCal                 matlab.ui.control.RadioButton
        ManualCal                       matlab.ui.control.RadioButton
        AutoCal                         matlab.ui.control.RadioButton
        AutoCalPanel                    matlab.ui.container.Panel
        WnSpinner                       matlab.ui.control.Spinner
        WnSpinnerLabel                  matlab.ui.control.Label
        nSpinner                        matlab.ui.control.Spinner
        nSpinnerLabel                   matlab.ui.control.Label
        SamplingWidth                   matlab.ui.control.Spinner
        Label_8                         matlab.ui.control.Label
        CaluculationAutoBtn             matlab.ui.control.Button
        SaveDataTab                     matlab.ui.container.Tab
        NextDataBtn                     matlab.ui.control.Button
        UITable_3                       matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        CalibrationRateField            matlab.ui.control.NumericEditField
        Label_38                        matlab.ui.control.Label
        SaveBtn                         matlab.ui.control.Button
        convAreaPanel_2                 matlab.ui.container.Panel
        withMarkCircum2Field_2          matlab.ui.control.NumericEditField
        Label_29                        matlab.ui.control.Label
        Circum2Field_2                  matlab.ui.control.NumericEditField
        Label_28                        matlab.ui.control.Label
        calCircumMarkField_2            matlab.ui.control.NumericEditField
        Label_27                        matlab.ui.control.Label
        convCircumLength_2              matlab.ui.control.NumericEditField
        Label_26                        matlab.ui.control.Label
        boundRecAreaPanel_2             matlab.ui.container.Panel
        recwithMarkCircum2Field_2       matlab.ui.control.NumericEditField
        Label_37                        matlab.ui.control.Label
        recCircum2Field_2               matlab.ui.control.NumericEditField
        Label_36                        matlab.ui.control.Label
        recCircumMarkField_2            matlab.ui.control.NumericEditField
        Label_35                        matlab.ui.control.Label
        recCircumLength_2               matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        CircleFitRhoPanel_2             matlab.ui.container.Panel
        rhowithMarkCircum2Field_2       matlab.ui.control.NumericEditField
        Label_33                        matlab.ui.control.Label
        rhoCircum2Field_2               matlab.ui.control.NumericEditField
        Label_32                        matlab.ui.control.Label
        rhoCircumMarkField_2            matlab.ui.control.NumericEditField
        Label_31                        matlab.ui.control.Label
        rhoCircumLength_2               matlab.ui.control.NumericEditField
        Label_30                        matlab.ui.control.Label
        DateField                       matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        ModelNameField                  matlab.ui.control.EditField
        Label_25                        matlab.ui.control.Label
        FileNameField_3                 matlab.ui.control.EditField
        FileNameField_2                 matlab.ui.control.EditField
        FileNameField                   matlab.ui.control.EditField
        Label_24                        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        dateMode                        matlab.ui.container.ButtonGroup
        DataAuto                        matlab.ui.control.RadioButton
        DataOnePoint                    matlab.ui.control.RadioButton
        DataRoi                         matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Mode % 解析モードかデモモード
        ptCloud % 蹄のオリジナルデータ（途中で校正球は排除）
        previousPtCloud % 点群データ更新前のデータ 
        croi
        selectSphere
        outOfSphere
        rotationAngler
        trans
        rate
        pclatent
        coeffMatrix
        updatePoint
        ptCloudLimit
        trimUp
        trimDown
        AfterPCAPoint
        sliceWidthT
        ManualSectionPoint
        convCircum
        convCircum_diff2
        convCircumWithMark 
        convCircum_diff2_withMark
        recCircum
        recCircum_diff2
        recCircumWithMark
        recCircum_diff2_withMark
        rhoCircum
        rhoCircum_diff2
        rhoCircumWithMark
        rhoCircum_diff2_withMark
        AtCircumPt_conv
        AtCircumPt_conv2
        AtCircumPt_rec
        AtCircumPt_rec2
        AtCircumPt_rho
        AtCircumPt_rho2
        fig
        OnePointSelectfig 
        sel_X
        sel_Y 
        sel_Z% Description
        AtCircumAt % Description
        CircumOnePoint
        CircumOnePoint_2
    end
    
    methods (Access = private)
        
        function updatePoint = callPreviousData(app, previousPoint) %#ok<INUSD> 
            close all;
            updatePoint = previousPoint;
            previousPoint = []; %#ok<NASGU> 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;

            m = load('marker.mat');
            ms = [m.m1, m.m2, m.m3];
            mean_m = mean(ms);
            app.MarkerSizeField.Value = mean_m;
            app.rotationAngler = [0 0 0];
            app.trans = [0 0 0];
            app.Mode = 'Analyze_mode';
            app.coeffMatrix = [1,0,0;0,1,0;0,0,1];
        end

        % Selection changed function: modeChangeGroup
        function modeChangeGroupSelectionChanged(app, event)
            selectedBtn = app.modeChangeGroup.SelectedObject;
            app.TabGroup.SelectedTab = app.CalibartionTab;
%             isShow = true;
            switch selectedBtn.Text
                case '解析'
                    close(app.main);
                    mainApp;
                case 'デモ'
                    app.Mode = 'Demo_mode';
                    s = load('demoptCloud.mat');
                    app.ptCloud = s.ptCloud;
                    app.outOfSphere = s.outOfSphere;
                    app.selectSphere = s.savept;    
                    pt = app.selectSphere.Location;
                    xy_pt = [pt(:,1) pt(:,2)];
                    zx_pt = [pt(:,3) pt(:,1)];
                    yz_pt = [pt(:,2) pt(:,3)];
                    app.importedfileField.Value = 'DemoData.stl';

                    plot(app.UIAxes6_xy, xy_pt(:,1), xy_pt(:,2), '.');
                    daspect([1 1 1]);
                    grid on;
                    plot(app.UIAxes6_zx, zx_pt(:,1), zx_pt(:,2), '.');
                    grid on;
                    daspect([1 1 1]);
                    plot(app.UIAxes6_yz, yz_pt(:,1), yz_pt(:,2), '.');
                    grid on;
                    daspect([1 1 1]);
                    app.RotManual.Enable = "off";
                    app.cutMarkerBtn.Enable = "off";
                    app.regionSetBtn.Enable = "off";
                    app.ROIButton.Enable = "off";

            end
            pcshowWithAxes(app.ptCloud, 'demo original');
            pcshowWithAxes(app.selectSphere, 'demo Sphere');
            pcshowWithAxes(app.outOfSphere, 'demo OutOfSphere');
%             pcshowWithAxes()
        end

        % Button pushed function: RebootButton
        function RebootButtonPushed(app, event)
             close(app.main);
             mainApp;
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all;
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            filename = uigetfile({'*.stl';'*.ply'}, ...
                'Select a file', ...
                'C:\Users\piyorin\dev\AnalyzeHoof\3dData');
            isShow = true;
            
            try
                app.ptCloud = import3Dmodel(filename, isShow);
                app.importedfileField.Value = filename;
            catch 
                app.importedfileField.Value = 'モデルはまだインポートされてません';
                return;
            end
            ax = gca;
            app.croi = images.roi.Cuboid(ax, 'Rotatable', 'all', 'Color', 'r');
            draw(app.croi);
        end

        % Button pushed function: regionSetBtn
        function regionSetBtnButtonPushed(app, event)
            xmin = app.croi.Position(1);
            ymin = app.croi.Position(2);
            zmin = app.croi.Position(3);
            xmax = app.croi.Position(4)+app.croi.Position(1);
            ymax = app.croi.Position(5)+app.croi.Position(2);
            zmax = app.croi.Position(6)+app.croi.Position(3);
            
            newroi = [xmin xmax ymin ymax zmin zmax];
            cindices = findPointsInROI(app.ptCloud, newroi);
            sPoint = select(app.ptCloud, cindices);
            pcshowWithAxes(sPoint, 'Select Area');

            maxDist = app.maxDistField.Value;

            [~, inlierIn, out] = pcfitsphere(app.ptCloud ,maxDist, 'MaxNumTrials',20000, 'SampleIndices',cindices);
            app.selectSphere = select(app.ptCloud,inlierIn);
            app.outOfSphere = select(app.ptCloud, out);
            pcshow(app.ptCloud, "AxesVisibility","on");
            hold on;             

            pcshow(app.selectSphere.Location, 'r');
            hold off;
            [~, score, ~] = pcaForptCloud(app.selectSphere, false);
            scorePlotAxes(score);
            app.selectSphere = pointCloud(score);
            xy_pt = [score(:,1) score(:,2)];
            zx_pt = [score(:,3) score(:,1)];
            yz_pt = [score(:,2) score(:,3)];

            plot(app.UIAxes6_xy, xy_pt(:,1), xy_pt(:,2), '.');
            grid on;
            plot(app.UIAxes6_zx, zx_pt(:,1), zx_pt(:,2), '.');
            grid on;
            plot(app.UIAxes6_yz, yz_pt(:,1), yz_pt(:,2), '.');
            grid on;
            
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            ax = gca;
            app.croi = images.roi.Cuboid(ax, 'Rotatable', 'all', 'Color', 'r');
            draw(app.croi);
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            close all;
            translation = [0 0 0];
            rotation = app.rotationAngler;
            tfrom = rigidtform3d(rotation,translation);
            pc = pctransform(app.selectSphere, tfrom);
            app.selectSphere = pc;
%             figure('Name','Rotated');
            pcshowWithAxes(pc, 'Rotated');
            view([0 0])
            pcpoint = pc.Location;
            xy_pt = [pcpoint(:,1) pcpoint(:,2)];
            zx_pt = [pcpoint(:,3) pcpoint(:,1)];
            yz_pt = [pcpoint(:,2) pcpoint(:,3)];
 
            plot(app.UIAxes6_xy, xy_pt(:,1), xy_pt(:,2), '.');
            grid on;
            plot(app.UIAxes6_zx, zx_pt(:,1), zx_pt(:,2), '.');
            grid on;
            plot(app.UIAxes6_yz, yz_pt(:,1), yz_pt(:,2), '.');
            grid on;

            app.XSlider.Value = 0;
            app.YSlider.Value = 0;
            app.ZSlider.Value = 0;
            app.xRotField.Value =0;
            app.yRotField.Value = 0;
            app.zRotField.Value = 0;
            app.rotationAngler = [0 0 0];
            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            R = app.rotationAngler;
            app.rotationAngler = R + [value 0 0];
            app.xRotField.Value = app.rotationAngler(1);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;

            R = app.rotationAngler;
            app.rotationAngler = R + [0 value 0];
            app.yRotField.Value = app.rotationAngler(2);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            R = app.rotationAngler;
            app.rotationAngler = R + [0 0 value];
            app.zRotField.Value = app.rotationAngler(3);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.rotationAngler = [0 0 0];
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;
            app.ZSlider.Value = 0;
            app.xRotField.Value =0;
            app.yRotField.Value = 0;
            app.zRotField.Value = 0;
        end

        % Button pushed function: cutMarkerBtn
        function cutMarkerBtnButtonPushed(app, event)
            value = app.cutAtField.Value;
            sel = app.selectSphere.Location;
            index = sel(:,3) > value;
            newsel = sel(index, :);
            app.selectSphere = pointCloud(newsel);
            close all;
            pcshowWithAxes(app.selectSphere, 'Use this Sphere ?');
        end

        % Button pushed function: CalMarkerRate
        function CalMarkerRateButtonPushed(app, event)
            [~, score, ~] = pcaForptCloud(app.selectSphere, false,[100 500 500 400]);
            scorePlotAxes(score, [600 500 500 400]);
            proJ = score(:,[1 2]);
            x = proJ(:,1);
            y = proJ(:,2);

            K = convhull(x, y);
            kx = x(K); ky = y(K);
            % 凸包選択点群を用いて円近似を行う
            [cxk, cyk, crk] = CircleFitting(kx, ky);
%             [thetaSorted, rhoSorted] = sortPointOnPolar([kx ky]);

            theta=[0:0.1:2*pi 0];
            x_k=crk*cos(theta)+cxk;
            y_k=crk*sin(theta)+cyk;
            
%             計算した校正球直径
            dia = 2*crk;
            app.MarkerDiaField.Value = dia;
            realDia = app.MarkerSizeField.Value;
            
            rt = dia / realDia;
            app.rate = rt;
            app.rateField.Value = rt;

            plot(app.UIAxes, kx,ky, '.',x_k, y_k, '-r');
        end

        % Button pushed function: CalibrationOKButton
        function CalibrationOKButtonPushed(app, event)
            close all;
            outPtC = app.outOfSphere; 
            app.ptCloud = outPtC;
            app.previousPtCloud = app.ptCloud;
            isShow = true;
            [coeff, score, latent] = pcaForptCloud(outPtC, isShow, [100 500 500 400]);
            app.pclatent = latent;
            app.coeffMatrix = coeff;
            scorePlotAxes(score, [650 500 900 400]);
            view([0 0]);
            ax = gca;
            ax.Projection = "orthographic";
            scorePt = pointCloud(score);
            app.updatePoint = scorePt;
            xli = scorePt.XLimits;
            yli = scorePt.YLimits;
            zli = scorePt.ZLimits;

            app.ptCloudLimit = [xli; yli; zli];
            app.upperSlider.Limits = xli;
            app.upperSlider.Value = xli(2);
            app.downSlider.Limits = xli;
            app.downSlider.Value = xli(1);
            app.ManualPosturePanel.Enable = "off";
            
            app.TabGroup.SelectedTab = app.PostureTab;
        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            close all;
            Point = app.updatePoint.Location;
            pc = app.ptCloud.Location;
            app.trimUp = app.upperSlider.Value;
            app.trimDown = app.downSlider.Value;
            up = app.trimUp;
            down = app.trimDown;
            Point = Point(Point(:,1) > down & up > Point(:,1), :);
%             switch app.trimAxes 
%                 case 'x'
%                     Point = Point(Point(:,1) > down & up > Point(:,1), :);
%                 case 'y'
%                     Point = Point(Point(:,2) > down & up > Point(:,2), :);
%                 case 'z'
%                     Point = Point(Point(:,3) > down & up > Point(:,3), :);
%             end
            cutPt = pointCloud(Point);
            [coeff, score, ~] = pcaForptCloud(cutPt, true);
            app.coeffMatrix = app.coeffMatrix * coeff;
            scorePlotAxes(score);
            posturePoint = pc*app.coeffMatrix;
          
            n_posturePoint = normalize(posturePoint, 'center', 'mean');
            
            pcshowWithAxes(n_posturePoint, 'Norm Posture');
            hold on;
            quiver3(0,0, 0, 1,0, 0,'r','AutoScale', "on", 'AutoScaleFactor',250, 'LineWidth',3.5);
            text(250, 0, 0, 'PC1', 'Color', 'w', 'FontSize',20);
            quiver3(0,0, 0, -1,0, 0,'r', 'AutoScale', "on", 'AutoScaleFactor',250, 'Marker','o','ShowArrowHead','off', 'LineWidth',3.5);
            hold off;

            app.previousPtCloud = app.updatePoint;
            app.updatePoint = pointCloud(score);
        end

        % Button pushed function: backpreviousBtn
        function backpreviousBtnPushed(app, event)
           app.updatePoint = callPreviousData(app, app.previousPtCloud);
           close all;
           [coeff, score, ~] = pcaForptCloud(app.updatePoint, true);
           scorePlotAxes(score);
           app.coeffMatrix = coeff;
           app.updatePoint = pointCloud(score);
        end

        % Button pushed function: modelUpdateBtn
        function modelUpdateBtnPushed(app, event)
            app.ptCloud = app.updatePoint;
            app.coeffMatrix = [];
            close all;
            [coeff, score] = pcaForptCloud(app.ptCloud, true);
            scorePlotAxes(score);
            app.coeffMatrix = coeff;
            app.updatePoint = pointCloud(score);
        end

        % Button pushed function: ResetPostureButton
        function ResetPostureButtonPushed(app, event)
%             app.updatePoint = app.ptCloud;
            close all;
            app.coeffMatrix = '';
            [coeff, score, ~] = pcaForptCloud(app.ptCloud, true);
            app.previousPtCloud = app.ptCloud;
            scorePlotAxes(score);
            app.coeffMatrix = coeff;
            scorePlotAxes(score);
            view([0 0]);
            app.updatePoint = pointCloud(score);

        end

        % Value changed function: upperSlider
        function upperSliderValueChanged(app, event)
            value = app.upperSlider.Value;
            app.trimUp = value;
            app.TrimmerUpField.Value = value;
        end

        % Value changed function: downSlider
        function downSliderValueChanged(app, event)
            value = app.downSlider.Value;
            app.trimDown = value;
            app.TrimmerDownField.Value = value;
        end

        % Button pushed function: DoneTrimBtn
        function DoneTrimBtnButtonPushed(app, event)
            close all;
            coeff = app.coeffMatrix;
            pt = app.ptCloud.Location;
            donePcaPt = pt * coeff;
            n_donePcaPt = normalize(donePcaPt, 'center', 'mean');
            app.updatePoint = pointCloud(n_donePcaPt);
            pcshowWithAxes(app.updatePoint, 'PCA-Posture-Completed');

            app.AfterPCAPoint = pointCloud(n_donePcaPt);
        end

        % Button pushed function: fixLegAndHoofTipDirectionBtn
        function fixLegAndHoofTipDirectionBtnButtonPushed(app, event)
            
            tipWidth = app.Slider.Value;
            app.previousPtCloud = app.updatePoint;
            sp = app.updatePoint;
%             disp(sp);
            sPoint= fixLegsDirection(sp, 'x', 0.5);
            hoofDir = app.iffixHoofDirSwitch.Value;
            if hoofDir == "On"
                pc= fixHoofTipDirection(sPoint, 'x', tipWidth, 2);
            else
                app.updatePoint = sPoint;
                scorePlotAxes(sPoint.Location);
                return;
            end
            app.updatePoint = pc;
%             app.fixedPoint = sp.Location;

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.HoofTipLength.Value = value;
            app.previousPtCloud = app.AfterPCAPoint;
            sp = app.AfterPCAPoint;
            sPoint= fixLegsDirection(sp, 'x', 0.5);
            pc= fixHoofTipDirection(sPoint, 'x', value, 2);
            app.updatePoint = cp;
        end

        % Button pushed function: CtrZBtn
        function CtrZBtnPushed(app, event)
            app.updatePoint = callPreviousData(app, app.previousPtCloud);
        end

        % Button pushed function: postureOKBtn
        function postureOKBtnButtonPushed(app, event)
            close all;
            sp = app.updatePoint;
            pcshowWithAxes(sp, 'Next Calculate Section');
            app.TabGroup.SelectedTab = app.CalculateTab;
        end

        % Selection changed function: AutoOrManual
        function AutoOrManualSelectionChanged(app, event)
            selectedButton = app.AutoOrManual.SelectedObject;
            try 
                pcshowWithAxes(app.ptCloud, 'Original Data');
            catch 
                close all;
                return;
            end
            switch selectedButton.Text
                case '自動'
                    app.PostureControlPanel.Enable = "on";
                    app.ManualPosturePanel.Enable = "off";
                    [score, coeff, ~] = pcaForptCloud(app.ptCloud, true);
                    scorePlotAxes(score);
                    app.updatePoint = pointCloud(score);
                    app.coeffMatrix = coeff;
                case '手動'
                    app.PostureControlPanel.Enable = "off";
                    app.ManualPosturePanel.Enable = "on";
            end
%             app.RotAxes = app.rotateAxes.SelectedObject.Text;

        end

        % Selection changed function: DataSelection
        function DataSelectionSelectionChanged(app, event)
            selectedButton = app.DataSelection.SelectedObject;
            close all;
            switch selectedButton.Text
                case 'オリジナル'
                    app.ManualSectionPoint = app.ptCloud;
                    pcshowWithAxes(app.ManualSectionPoint, 'Original Data');
                case 'PCA済み'
                    try
                        app.ManualSectionPoint = pointCloud(app.AfterPCAPoint);
                        afp = app.AfterPCAPoint;
%                         pcshowWithAxes(app.ManualSectionPoint, 'After PCA Data');
                        scorePlotAxes(afp);
                    catch
                        message = 'データがありません';
                        uialert(app.main, message, 'NO DATA');
                    end
                case '肢方向修正済み'
                    try 
                        app.ManualSectionPoint = app.updatePoint;
                        upt = app.updatePoint.Location;
%                         pcshowWithAxes(app.ManualSectionPoint, '肢方向修正済み');
                        scorePlotAxes(upt);
                    catch
                        message = 'データがありません';
                        uialert(app.main, message, 'NO DATA');
                    end
            end
        end

        % Value changed function: XSlider_3
        function XSlider_3ValueChanged(app, event)
            value = app.XSlider_3.Value;
            R = app.rotationAngler;
            app.rotationAngler = R + [value 0 0];
            app.ManualxRotField.Value = app.rotationAngler(1);
        end

        % Value changed function: YSlider_3
        function YSlider_3ValueChanged(app, event)
            value = app.YSlider_3.Value;
            R = app.rotationAngler;
            app.rotationAngler = R + [0 value 0];
            app.ManualyRotField.Value = app.rotationAngler(2);
        end

        % Value changed function: ZSlider_3
        function ZSlider_3ValueChanged(app, event)
            value = app.ZSlider_3.Value;
            R = app.rotationAngler;
            app.rotationAngler = R + [0 0 value];
            app.ManualzRotField.Value = app.rotationAngler(3);
        end

        % Button pushed function: ManualRotationBtn
        function ManualRotationBtnPushed(app, event)
            close all;
            manualPtCloud = app.ManualSectionPoint;
            app.previousPtCloud = manualPtCloud;
            translation = [0 0 0];
            rotation = app.rotationAngler;
            tfrom = rigidtform3d(rotation,translation);
            pc = pctransform(manualPtCloud, tfrom);
            app.ManualSectionPoint = pc;
           
            pcshowWithAxes(pc, 'Rotated');
            
            app.XSlider_3.Value = 0;
            app.YSlider_3.Value = 0;
            app.ZSlider_3.Value = 0;
            app.ManualxRotField.Value =0;
            app.ManualyRotField.Value = 0;
            app.ManualzRotField.Value = 0;
            app.rotationAngler = [0 0 0];
            
        end

        % Button pushed function: ManualPosOKButton
        function ManualPosOKButtonPushed(app, event)
            app.updatePoint = app.ManualSectionPoint;
            close all;
            sp = app.ManualSectionPoint;
            pcshow(sp, 'AxesVisibility','on');
            
            app.TabGroup.SelectedTab = app.CalculateTab;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.sliceWidthT = value;
        end

        % Button pushed function: CaluculationAutoBtn
        function CaluculationAutoBtnButtonPushed(app, event)
            close all;
            sPoint = app.updatePoint;
            axes = app.CalculationDirectionAxes.SelectedObject.Text;
            app.sliceWidthT = app.TEditField.Value;
            sampleWidth = app.SamplingWidth.Value;
            t = app.sliceWidthT;
            rt = app.rate;
            switch axes
                case 'x'
                    axIdx = 1;
                case 'y'
                    axIdx = 2;
                case 'z'
                    axIdx = 3;
            end
            ConvData = sliceData(sPoint, axes, t);
            RecAreaData  = CalTankei(sPoint, axes, t);
            CircleRhoData = sliceCircleRho(sPoint, axes, t, false);
            n = app.nSpinner.Value;
            Wn = app.WnSpinner.Value;
%             MinPro = 1000;
            % 凸包探索
            [index1_conv,index2_conv, Pos1_c,Pos2_c,pos_conv, yset_conv, TF_conv] = SearchCircumpos(ConvData, sampleWidth,n, Wn);
            % 外接矩形探索
            [index1_rec,index2_rec, Pos1_re,Pos2_re,pos_rec, yset_rec, TF_rec] = SearchCircumpos(RecAreaData,sampleWidth, n, Wn);
            %　円近似探索
            [index1_rho,index2_rho, Pos1_rh,Pos2_rh,pos_rho, yset_rho, TF_rho] = SearchCircumpos(CircleRhoData(:,1:3),sampleWidth, n, Wn);

            yc = yset_conv(:,1); v1_c = yset_conv(:,2); v2_c= yset_conv(:,3);
            tf_conv1 = TF_conv(:,1);
            tf_conv2 = TF_conv(:,2);
            plot(app.UIAxes_csArea, pos_conv, yc);
            plot(app.UIAxes_csArea_diff1, pos_conv, v1_c, 'b', pos_conv(tf_conv1), v1_c(tf_conv1), 'ro');
            plot(app.UIAxes_csArea_diff2, pos_conv, v2_c, 'r', pos_conv(tf_conv2), v2_c(tf_conv2), 'o');
             
            y_rec = yset_rec(:,1); v1_rec = yset_rec(:,2); v2_rec= yset_rec(:,3);
            tf_rec1 = TF_rec(:,1);
            tf_rec2 = TF_rec(:,2);
            plot(app.UIAxes_recArea, pos_rec, y_rec);
            plot(app.UIAxes_recArea_diff1, pos_rec, v1_rec, 'b', pos_rec(tf_rec1), v1_rec(tf_rec1), 'ro');
            plot(app.UIAxes_recArea_diff2, pos_rec, v2_rec, 'r', pos_rec(tf_rec2), v2_rec(tf_rec2), 'o');

            y_rho = yset_rho(:,1); v1_rho = yset_rho(:,2); v2_rho= yset_rho(:,3);
            tf_rho1 = TF_rho(:,1);
            tf_rho2 = TF_rho(:,2);
            plot(app.UIAxes_rho, pos_rho, y_rho);
            plot(app.UIAxes_rho_diff1, pos_rho, v1_rho, 'b', pos_rho(tf_rho1), v1_rho(tf_rho1), 'ro');
            plot(app.UIAxes_rho_diff2, pos_rho, v2_rho, 'r', pos_rho(tf_rho2), v2_rho(tf_rho2), 'o');
            
            % 凸包面積をもちいた計算
            sample_convData = ConvData(index1_conv, :);
            sample_convData2 = ConvData(index2_conv, :);
            sp = app.updatePoint.Location;
            samplemin = Pos1_c(1); samplemax = Pos1_c(2);
            samplemin2 = Pos2_c(1); samplemax2 = Pos2_c(2);
            samplePoint_conv = sp(sp(:,axIdx) > samplemin & sp(:, axIdx) < samplemax, :);
            samplePoint_conv2 = sp(sp(:,axIdx) > samplemin2 & sp(:, axIdx) < samplemax2, :);
           
            mc1 = mean(sample_convData(:,2));
            mc2 = mean(sample_convData2(:,2));
            app.convCircum = mc1;
            app.convCircum_diff2 = mc2;
            app.convCircumWithMark = app.convCircum/rt;
            app.convCircum_diff2_withMark = app.convCircum_diff2/rt;
                
            close all;
            sfig(1) = pcshowWithAxes(app.updatePoint, 'Marking_by_convhullSearch', [100 500 500 400]);
            hold on; grid on;
            pcshow(samplePoint_conv, 'b', 'AxesVisibility','on');
            pcshow(samplePoint_conv2, 'r', 'AxesVisibility', 'on');
            view([0 0])
            hold off;

            app.convCircumLength.Value = app.convCircum;
            app.Circum2Field.Value = app.convCircum_diff2; 
            app.calCircumMarkField.Value = app.convCircumWithMark;
            app.withMarkCircum2Field.Value = app.convCircum_diff2_withMark;

            app.AtCircumPt_conv = pointCloud(samplePoint_conv);
            app.AtCircumPt_conv2 = pointCloud(samplePoint_conv2);

            % 外接矩形探索
            sample_recData = RecAreaData(index1_rec, :);
            sample_recData2 = RecAreaData(index2_rec, :);
            samplemin = Pos1_re(1); samplemax = Pos1_re(2);
            samplemin2 = Pos2_re(1); samplemax2 = Pos2_re(2);
            samplePoint_rec= sp(sp(:,axIdx) > samplemin & sp(:, axIdx) < samplemax, :);
            samplePoint_rec2 = sp(sp(:,axIdx) > samplemin2 & sp(:, axIdx) < samplemax2, :);
           
            app.recCircum = mean(sample_recData(:,2));
            app.recCircum_diff2 = mean(sample_recData2(:,2));
            app.recCircumWithMark = app.recCircum/rt;
            app.recCircum_diff2_withMark = app.recCircum_diff2/rt;
            
            sfig(2) = pcshowWithAxes(app.updatePoint, 'Marking_by_BoundRectangle_Search',[650 500 500 400]);
            hold on; grid on;
            pcshow(samplePoint_rec, 'b', 'AxesVisibility','on');
            pcshow(samplePoint_rec2, 'r', 'AxesVisibility', 'on');
            view([0 0])
            hold off;

            app.recCircumLength.Value = app.recCircum;
            app.recCircum2Field.Value = app.recCircum_diff2; 
            app.recCircumMarkField.Value = app.recCircumWithMark;
            app.recwithMarkCircum2Field.Value = app.recCircum_diff2_withMark;

            app.AtCircumPt_rec = pointCloud(samplePoint_rec);
            app.AtCircumPt_rec2 = pointCloud(samplePoint_rec2);% Description

            % 円近似探索
            sample_rhoData =CircleRhoData(index1_rho, 1:3);
            sample_rhoData2 = CircleRhoData(index2_rho, 1:3);
            samplemin = Pos1_rh(1); samplemax = Pos1_rh(2);
            samplemin2 = Pos2_rh(1); samplemax2 = Pos2_rh(2);
            samplePoint_rho= sp(sp(:,axIdx) > samplemin & sp(:, axIdx) < samplemax, :);
            samplePoint_rho2 = sp(sp(:,axIdx) > samplemin2 & sp(:, axIdx) < samplemax2, :);
           
            app.rhoCircum = mean(sample_rhoData(:,2));
            app.rhoCircum_diff2 = mean(sample_rhoData2(:,2));
            app.rhoCircumWithMark = app.rhoCircum/rt;
            app.rhoCircum_diff2_withMark = app.rhoCircum_diff2/rt;

            sfig(3) = pcshowWithAxes(app.updatePoint, 'Marking_by_CircleFit_Search', [1200 500 500 400]);
            hold on; grid on;
            pcshow(samplePoint_rho, 'b', 'AxesVisibility','on');
            pcshow(samplePoint_rho2, 'r', 'AxesVisibility', 'on');
            view([0 0])
            hold off;

            app.fig = sfig;
            app.rhoCircumLength.Value = app.rhoCircum;
            app.rhoCircum2Field.Value = app.rhoCircum_diff2; 
            app.rhoCircumMarkField.Value = app.rhoCircumWithMark;
            app.rhowithMarkCircum2Field.Value = app.rhoCircum_diff2_withMark;

            app.AtCircumPt_rho = pointCloud(samplePoint_rho);
            app.AtCircumPt_rho2 = pointCloud(samplePoint_rho2);
            

        end

        % Button pushed function: ToSaveDataTabBtn
        function ToSaveDataTabBtnButtonPushed(app, event)
            %             close all;
            dt = datetime('now');
            modelname = app.importedfileField.Value;
            app.ModelNameField.Value = modelname;
            app.DateField.Value = string(dt);

            selectmode = app.AutoOrManualCal.SelectedObject.Text;
            if selectmode == "一点指定"
                app.convCircumLength_2.Value = app.convCircum;
                app.Circum2Field_2.Value = app.convCircum_diff2; 
                
                app.calCircumMarkField_2.Value = 0;
                app.withMarkCircum2Field_2.Value = 0;
    
                app.recCircumLength_2.Value = 0;
                app.recCircum2Field_2.Value = 0; 
                app.recCircumMarkField_2.Value = 0;
                app.recwithMarkCircum2Field_2.Value = 0;
                
                app.rhoCircumLength_2.Value = 0;
                app.rhoCircum2Field_2.Value = 0; 
                app.rhoCircumMarkField_2.Value = 0;
                app.rhowithMarkCircum2Field_2.Value = 0;
    
            else
                app.convCircumLength_2.Value = app.convCircum;
                app.Circum2Field_2.Value = app.convCircum_diff2; 
    
                app.calCircumMarkField_2.Value = app.convCircumWithMark;
                app.withMarkCircum2Field_2.Value = app.convCircum_diff2_withMark;
    
                app.recCircumLength_2.Value = app.recCircum;
                app.recCircum2Field_2.Value = app.recCircum_diff2; 
                app.recCircumMarkField_2.Value = app.recCircumWithMark;
                app.recwithMarkCircum2Field_2.Value = app.recCircum_diff2_withMark;
                
                app.rhoCircumLength_2.Value = app.rhoCircum;
                app.rhoCircum2Field_2.Value = app.rhoCircum_diff2; 
                app.rhoCircumMarkField_2.Value = app.rhoCircumWithMark;
                app.rhowithMarkCircum2Field_2.Value = app.rhoCircum_diff2_withMark;
            end 

            app.CalibrationRateField.Value = app.rate;
           
            mode = app.Mode;
            if mode == "Demo_mode"
                app.FileNameField.Value = "demoConv";
                app.FileNameField_2.Value = "demoRec";
                app.FileNameField_3.Value = "demoCircle";
            end
            
            app.TabGroup.SelectedTab = app.SaveDataTab;
        end

        % Selection changed function: AutoOrManualCal
        function AutoOrManualCalSelectionChanged(app, event)
            selectedButton = app.AutoOrManualCal.SelectedObject;
            close all;

            switch selectedButton.Text
                case '自動'   
                    app.AutoCalPanel.Enable = 'on';
                case '一点指定'
                    app.OnePointSelectfig = figure("Name","Manual Select");
                    pcshow(app.updatePoint, "AxesVisibility","on");
                    app.dateMode.SelectedObject = app.DataOnePoint;
                    app.FileNameField.Value = "CircumDataOnlyOne";
                    app.FileNameField_2.Value = "";
                    app.FileNameField_3.Value = "";
                    app.DataModeField.Value = "一点指定";
                case '領域選択'
                    app.dateMode.SelectedObject = app.DataRoi;
                    app.FileNameField.Value = "CircumDataROI";
                    app.DataModeField.Value = "領域選択";
                
                    app.AutoCalPanel.Enable = 'off';
            end 
        end

        % Button pushed function: getPositionBtn
        function getPositionBtnButtonPushed(app, event)
            try
                [x, y, z] = getDatatip(app.OnePointSelectfig );
            catch 
                message = {'副蹄部の点群データチップを選んで'};
                uialert(app.main, message, 'Choose Nothing');
                return;
            end
            [app.sel_X, app.sel_Y, app.sel_Z] = makeDatatipToDouble(x, y, z); 

            app.XEditField.Value = app.sel_X;
            app.YEditField.Value = app.sel_Y;
            app.ZEditField.Value = app.sel_Z;
        end

        % Button pushed function: ReChooseBtn
        function ReChooseBtnButtonPushed(app, event)
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.sel_X = 0;app.sel_Y = 0;app.sel_Z = 0;
        end

        % Button pushed function: CalculationManualy
        function CalculationManualyButtonPushed(app, event)
            Axes = 'x';
            switch Axes 
                case 'x'
                    dt = app.sel_X;
                    make2d = [2 3];
                   
                case 'y'
                    dt = app.sel_Y;
                    make2d = [1 3];
                   
                case 'z'
                    dt = app.sel_Z;
                    make2d = [2 3];
                    
            end
            app.OnePointSelection.Enable = "on";
            app.ROISelectionPanel.Enable = "off";
            slashedPoint = slashPoint(app.updatePoint.Location, 2.5, dt, 'x');
            slash2d = slashedPoint(:,make2d);
            [k, ~] = convhull(slash2d);
            slash_k = [slash2d(k,1) slash2d(k,2)];
            [thetaSorted_k, rhoSorted_k] = sortPointOnPolar(slash_k);
            [x_k, y_k] = pol2cart(thetaSorted_k, rhoSorted_k);
            len = calculateLengthForPoint([x_k y_k]);
            atCircumPt = pointCloud(slashedPoint);

            pcshowWithAxes(app.updatePoint, "select OnePoint");
            hold on;
            pcshow(atCircumPt.Location, 'r');
            hold off;

            app.AtCircumPt_conv = atCircumPt;
            app.convCircum = len;
            app.convCircumWithMark  = len/app.rate;

            app.convCircumLength.Value = app.convCircum;
            app.calCircumMarkField.Value = app.convCircumWithMark;

        end

        % Button pushed function: ROIButton_2
        function ROIButton_2Pushed(app, event)
            
        end

        % Button pushed function: ClipingWithROI
        function ClipingWithROIButtonPushed(app, event)
            
        end

        % Button pushed function: SaveBtn
        function SaveBtnButtonPushed(app, event)
            uit = app.UITable;
            tp = uit.Data;

            uit2 = app.UITable_2;
            tp2 = uit2.Data;

            uit3 = app.UITable_3;
            tp3 = uit3.Data;

            columnList = {'日付', 'モデル名','校正倍率', '周囲長', '2階微分周囲長', ...
                '校正済周囲長', '校正済2階周囲長', ...
                '蹄背壁長', '2階蹄背壁長', ...
                '校正蹄背壁長', '校正2階蹄背壁長'};
             
            set(uit, 'ColumnName', columnList);
            set(uit2, 'ColumnName', columnList);
            set(uit3, 'ColumnName', columnList);

            date = string(app.DateField.Value);
            model = string(app.ModelNameField.Value);
            Calibrate = app.rate; 
            % 凸包探索結果の保存
            circum_conv = app.convCircum;
            circum_conv2 = app.convCircum_diff2;
            circum_conv_wm = app.convCircumWithMark;
            circum_conv_d2_wm = app.convCircum_diff2_withMark;

            hf_conv = predictHoofBackWall(circum_conv);
            hf_conv2 = predictHoofBackWall(circum_conv2);
            hf_conv_wm = predictHoofBackWall(circum_conv_wm);
            hf_conv_d2_wm = predictHoofBackWall(circum_conv_d2_wm);

            T = table(date, model, Calibrate, circum_conv, circum_conv2, circum_conv_wm, circum_conv_d2_wm, hf_conv, hf_conv2, hf_conv_wm, hf_conv_d2_wm);
            Tnew = [tp;T];
            
            mat = [date, model, Calibrate, circum_conv, circum_conv2, circum_conv_wm, circum_conv_d2_wm, hf_conv, hf_conv2, hf_conv_wm, hf_conv_d2_wm];
            ext = ".xlsx";
            savename = string(app.FileNameField.Value);
            savexlsx = append(savename, ext);
            writematrix(mat, savexlsx, 'WriteMode','append');
            
            uit.RowName = 'numbered';            
            uit.Data = Tnew;
            % 矩形面積探索の結果保存
%
            circum_rec = app.recCircum;
            circum_rec2 = app.recCircum_diff2;
            circum_rec_wm = app.recCircumWithMark;
            circum_rec_d2_wm = app.recCircum_diff2_withMark;

            hf_rec = predictHoofBackWall(circum_rec);
            hf_rec2 = predictHoofBackWall(circum_rec2);
            hf_rec_wm = predictHoofBackWall(circum_rec_wm);
            hf_rec_d2_wm = predictHoofBackWall(circum_rec_d2_wm);

            T2 = table(date, model, Calibrate, circum_rec, circum_rec2, circum_rec_wm, circum_rec_d2_wm, hf_rec, hf_rec2, hf_rec_wm, hf_rec_d2_wm);
            T2new = [tp2;T2];
            
            mat2 = [date, model, Calibrate, circum_rec, circum_rec2, circum_rec_wm, circum_rec_d2_wm, hf_rec, hf_rec2, hf_rec_wm, hf_rec_d2_wm];
            savename2 = string(app.FileNameField_2.Value);
            savexlsx2 = append(savename2, ext);
            writematrix(mat2, savexlsx2, 'WriteMode','append');
            
            uit2.RowName = 'numbered';            
            uit2.Data = T2new;

            % 円近似半径探索結果のの保存
            circum_rho = app.rhoCircum;
            circum_rho2 = app.rhoCircum_diff2;
            circum_rho_wm = app.rhoCircumWithMark;
            circum_rho_d2_wm = app.rhoCircum_diff2_withMark;

            hf_rho = predictHoofBackWall(circum_rho);
            hf_rho2 = predictHoofBackWall(circum_rho2);
            hf_rho_wm = predictHoofBackWall(circum_rho_wm);
            hf_rho_d2_wm = predictHoofBackWall(circum_rho_d2_wm);

            T3 = table(date, model, Calibrate, circum_rho, circum_rho2, circum_rho_wm, circum_rho_d2_wm, hf_rho, hf_rho2, hf_rho_wm, hf_rho_d2_wm);
            T3new = [tp3;T3];
            
            mat3 = [date, model, Calibrate, circum_rho, circum_rho2, circum_rho_wm, circum_rho_d2_wm, hf_rho, hf_rho2, hf_rho_wm, hf_rho_d2_wm];
            savename3 = string(app.FileNameField_3.Value);
            savexlsx3 = append(savename3, ext);
            writematrix(mat3, savexlsx3, 'WriteMode','append');
            
            uit3.RowName = 'numbered';            
            uit3.Data = T3new;


            file = app.importedfileField.Value;
%             path = 'new3dData\';
            path = 'renew3dData/'; % macは/windowsは\
            idx = length(file);
            nfile = file(1:idx-4);
            newfile = [path, nfile, '.mat'];
            nPoint = app.updatePoint;
            atcir_c = app.AtCircumPt_conv;
            atcir_c2 = app.AtCircumPt_conv2;
            atcir_rec = app.AtCircumPt_rec;
            atcir_rec2 = app.AtCircumPt_rec2;
            atcir_rho = app.AtCircumPt_rho;
            atcir_rho2 = app.AtCircumPt_rho2;
            save(newfile, 'nPoint', 'atcir_c','atcir_c2', 'atcir_rec', 'atcir_rec2', 'atcir_rho', 'atcir_rho2', '-mat');

%             figpath = 'fig\';
            sfig = app.fig;
            figpath = 'fig/';
            figfile = [figpath, nfile, '.fig'];
            savefig(sfig, figfile);

            app.DateField.Value = '';
            app.ModelNameField.Value = '';
            app.CalibrationRateField.Value = 0;
    
            app.withMarkCircum2Field_2.Value = 0;
            app.Circum2Field_2.Value = 0;
            app.calCircumMarkField_2.Value = 0;
            app.convCircumLength_2.Value = 0;

            app.recwithMarkCircum2Field_2.Value = 0;
            app.recCircum2Field_2.Value = 0;
            app.recCircumMarkField_2.Value = 0;
            app.recCircumLength_2.Value = 0;

            app.rhowithMarkCircum2Field_2.Value = 0;
            app.rhoCircum2Field_2.Value = 0;
            app.rhoCircumMarkField_2.Value = 0;
            app.rhoCircumLength_2.Value = 0;

            app.ptCloud = '';
            app.coeffMatrix = '';
            app.AfterPCAPoint = '';
            app.previousPtCloud = '';
            app.selectSphere = '';
            app.rotationAngler = [0 0 0];
            app.trans = [0 0 0];
            app.rate = 0;
            app.pclatent = 0;
            app.outOfSphere = '';
            app.updatePoint = '';

        end

        % Button pushed function: NextDataBtn
        function NextDataBtnPushed(app, event)
             close(app.main);
             mainApp;
        end

        % Value changed function: iffixHoofDirSwitch
        function iffixHoofDirSwitchValueChanged(app, event)
%             value = app.iffixHoofDirSwitch.Value;
            sw_value = app.iffixHoofDirSwitch.Value;
            switch sw_value
                case "On"
                    app.Lamp.Color = '0.00,1.00,0.00';
                case "Off"
                    app.Lamp.Color = '0.50,0.50,0.50';
            end

        end

        % Button pushed function: hozonnButton
        function hozonnButtonPushed(app, event)
            path = 'renew3ddata/';
            file = app.importedfileField.Value;
            idx = length(file);
            nfile = file(1:idx-4);
            newfile = [path, nfile,'_only', '.mat'];
            nPoint = app.updatePoint;
            srate = app.rate;
            save(newfile, 'nPoint', 'srate', '-mat');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.main);
            app.CalibartionTab = uitab(app.TabGroup);
            app.CalibartionTab.Title = '校正';
            app.UIAxes6_yz = uiaxes(app.CalibartionTab);
            title(app.UIAxes6_yz, 'Y-Z')
            xlabel(app.UIAxes6_yz, 'Y')
            ylabel(app.UIAxes6_yz, 'Z')
            app.UIAxes6_xy = uiaxes(app.CalibartionTab);
            title(app.UIAxes6_xy, 'X-Y')
            xlabel(app.UIAxes6_xy, 'X')
            ylabel(app.UIAxes6_xy, 'Y')
            zlabel(app.UIAxes6_xy, 'Z')
            app.UIAxes6_zx = uiaxes(app.CalibartionTab);
            title(app.UIAxes6_zx, 'X-Z')
            xlabel(app.UIAxes6_zx, 'X')
            ylabel(app.UIAxes6_zx, 'Z')
            app.UIAxes6_zx.XDir = 'reverse';
            app.CalibrationPanel = uipanel(app.CalibartionTab);
            app.CalibrationPanel.Title = '校正球の調整';
            app.GridLayout3 = uigridlayout(app.CalibrationPanel);
            app.ROIButton = uibutton(app.GridLayout3, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.regionSetBtn = uibutton(app.GridLayout3, 'push');
            app.regionSetBtn.ButtonPushedFcn = createCallbackFcn(app, @regionSetBtnButtonPushed, true);
            app.regionSetBtn.Text = '領域設定';
            app.cutMarkerBtn = uibutton(app.GridLayout3, 'push');
            app.cutMarkerBtn.ButtonPushedFcn = createCallbackFcn(app, @cutMarkerBtnButtonPushed, true);
            app.cutMarkerBtn.Text = 'カット';
            app.CalMarkerRate = uibutton(app.GridLayout3, 'push');
            app.CalMarkerRate.ButtonPushedFcn = createCallbackFcn(app, @CalMarkerRateButtonPushed, true);
            app.CalMarkerRate.Text = '校正球計算';
            app.CalibrationResultPanel = uipanel(app.GridLayout3);
            app.CalibrationResultPanel.Title = '校正球計算結果';
            app.GridLayout = uigridlayout(app.CalibrationResultPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '採用円')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '校正球直径';
            app.MarkerDiaField = uieditfield(app.GridLayout, 'numeric');
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '校正倍率';
            app.rateField = uieditfield(app.GridLayout, 'numeric');
            app.RotManual = uipanel(app.GridLayout3);
            app.XSliderLabel = uilabel(app.RotManual);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.RotManual);
            app.XSlider.Limits = [-180 180];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.RotManual);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.RotManual);
            app.YSlider.Limits = [-180 180];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.RotManual);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.RotManual);
            app.ZSlider.Limits = [-180 180];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.xRotField = uieditfield(app.RotManual, 'numeric');
            app.yRotField = uieditfield(app.RotManual, 'numeric');
            app.zRotField = uieditfield(app.RotManual, 'numeric');
            app.RotationButton = uibutton(app.RotManual, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.ResetButton = uibutton(app.RotManual, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '校正球サイズ';
            app.MarkerSizeField = uieditfield(app.GridLayout3, 'numeric');
            app.MarkerSizeField.Value = 40;
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '球体最大距離';
            app.maxDistField = uieditfield(app.GridLayout3, 'numeric');
            app.maxDistField.Value = 2.5;
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'カット位置';
            app.cutAtField = uieditfield(app.GridLayout3, 'numeric');
            app.cutAtField.Value = -10;
            app.CalibrationOKButton = uibutton(app.CalibartionTab, 'push');
            app.CalibrationOKButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationOKButtonPushed, true);
            app.CalibrationOKButton.Text = 'OK';
            app.PostureTab = uitab(app.TabGroup);
            app.PostureTab.Title = '姿勢制御';
            app.AutoOrManual = uibuttongroup(app.PostureTab);
            app.AutoOrManual.SelectionChangedFcn = createCallbackFcn(app, @AutoOrManualSelectionChanged, true);
            app.Auto = uiradiobutton(app.AutoOrManual);
            app.Auto.Text = '自動';
            app.Auto.Value = true;
            app.Manual = uiradiobutton(app.AutoOrManual);
            app.Manual.Text = '手動';
            app.PostureControlPanel = uipanel(app.PostureTab);
            app.PostureControlPanel.Title = '姿勢制御自動';
            app.PCATrimmerAppButton = uibutton(app.PostureControlPanel, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'トリミング';
            app.TrimDirectionSelectButtonGroup = uibuttongroup(app.PostureControlPanel);
            app.TrimDirectionSelectButtonGroup.Title = 'メイン軸';
            app.xButton = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.zButton.Text = 'z';
            app.ResetPostureButton = uibutton(app.PostureControlPanel, 'push');
            app.ResetPostureButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPostureButtonPushed, true);
            app.ResetPostureButton.Text = 'Reset';
            app.postureOKBtn = uibutton(app.PostureControlPanel, 'push');
            app.postureOKBtn.ButtonPushedFcn = createCallbackFcn(app, @postureOKBtnButtonPushed, true);
            app.postureOKBtn.Text = 'OK';
            app.DoneTrimBtn = uibutton(app.PostureControlPanel, 'push');
            app.DoneTrimBtn.ButtonPushedFcn = createCallbackFcn(app, @DoneTrimBtnButtonPushed, true);
            app.DoneTrimBtn.Text = 'トリミング完了';
            app.Label_6 = uilabel(app.PostureControlPanel);
            app.Label_6.Text = '上端';
            app.TrimmerUpField = uieditfield(app.PostureControlPanel, 'numeric');
            app.Label_7 = uilabel(app.PostureControlPanel);
            app.Label_7.Text = '下端';
            app.TrimmerDownField = uieditfield(app.PostureControlPanel, 'numeric');
            app.UPLabel = uilabel(app.PostureControlPanel);
            app.UPLabel.Text = 'Up';
            app.upperSlider = uislider(app.PostureControlPanel);
            app.upperSlider.ValueChangedFcn = createCallbackFcn(app, @upperSliderValueChanged, true);
            app.DownLabel = uilabel(app.PostureControlPanel);
            app.DownLabel.Text = 'Down';
            app.downSlider = uislider(app.PostureControlPanel);
            app.downSlider.ValueChangedFcn = createCallbackFcn(app, @downSliderValueChanged, true);
            app.CtrZBtn = uibutton(app.PostureControlPanel, 'push');
            app.CtrZBtn.ButtonPushedFcn = createCallbackFcn(app, @CtrZBtnPushed, true);
            app.CtrZBtn.Text = '戻る';
            app.modelUpdateBtn = uibutton(app.PostureControlPanel, 'push');
            app.modelUpdateBtn.ButtonPushedFcn = createCallbackFcn(app, @modelUpdateBtnPushed, true);
            app.modelUpdateBtn.Text = 'モデルの更新';
            app.Panel_2 = uipanel(app.PostureControlPanel);
            app.fixLegAndHoofTipDirectionBtn = uibutton(app.Panel_2, 'push');
            app.fixLegAndHoofTipDirectionBtn.ButtonPushedFcn = createCallbackFcn(app, @fixLegAndHoofTipDirectionBtnButtonPushed, true);
            app.fixLegAndHoofTipDirectionBtn.Text = '肢、蹄方向の修正';
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '尖蹄';
            app.Slider = uislider(app.Panel_2);
            app.Slider.Limits = [10 45];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 35;
            app.iffixHoofDirSwitch = uiswitch(app.Panel_2, 'toggle');
            app.iffixHoofDirSwitch.ValueChangedFcn = createCallbackFcn(app, @iffixHoofDirSwitchValueChanged, true);
            app.iffixHoofDirSwitch.Value = 'On';
            app.Label_39 = uilabel(app.Panel_2);
            app.Label_39.Text = '蹄向き';
            app.Lamp = uilamp(app.Panel_2);
            app.HoofTipLength = uieditfield(app.Panel_2, 'numeric');
            app.backpreviousBtn = uibutton(app.PostureControlPanel, 'push');
            app.backpreviousBtn.ButtonPushedFcn = createCallbackFcn(app, @backpreviousBtnPushed, true);
            app.backpreviousBtn.Text = '戻る';
            app.ManualPosturePanel = uipanel(app.PostureTab);
            app.ManualPosturePanel.Title = '手動';
            app.DataSelection = uibuttongroup(app.ManualPosturePanel);
            app.DataSelection.SelectionChangedFcn = createCallbackFcn(app, @DataSelectionSelectionChanged, true);
            app.DataSelection.Title = 'データ選択';
            app.Original = uiradiobutton(app.DataSelection);
            app.Original.Text = 'オリジナル';
            app.Original.Value = true;
            app.PCAButton = uiradiobutton(app.DataSelection);
            app.PCAButton.Text = 'PCA済み';
            app.LegDirAlreadyFixed = uiradiobutton(app.DataSelection);
            app.LegDirAlreadyFixed.Text = '肢方向修正済み';
            app.ManualPosOKButton = uibutton(app.ManualPosturePanel, 'push');
            app.ManualPosOKButton.ButtonPushedFcn = createCallbackFcn(app, @ManualPosOKButtonPushed, true);
            app.ManualPosOKButton.Text = 'OK';
            app.RotOnPostureSectionPanel = uipanel(app.ManualPosturePanel);
            app.ZSlider_2Label = uilabel(app.RotOnPostureSectionPanel);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_3 = uislider(app.RotOnPostureSectionPanel);
            app.ZSlider_3.Limits = [-180 180];
            app.ZSlider_3.ValueChangedFcn = createCallbackFcn(app, @ZSlider_3ValueChanged, true);
            app.ManualzRotField = uieditfield(app.RotOnPostureSectionPanel, 'numeric');
            app.ManualyRotField = uieditfield(app.RotOnPostureSectionPanel, 'numeric');
            app.ManualxRotField = uieditfield(app.RotOnPostureSectionPanel, 'numeric');
            app.ManualRotationBtn = uibutton(app.RotOnPostureSectionPanel, 'push');
            app.ManualRotationBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualRotationBtnPushed, true);
            app.ManualRotationBtn.Text = 'Rotation';
            app.YSlider_3Label = uilabel(app.RotOnPostureSectionPanel);
            app.YSlider_3Label.Text = 'Y';
            app.YSlider_3 = uislider(app.RotOnPostureSectionPanel);
            app.YSlider_3.Limits = [-180 180];
            app.YSlider_3.ValueChangedFcn = createCallbackFcn(app, @YSlider_3ValueChanged, true);
            app.XSlider_3Label = uilabel(app.RotOnPostureSectionPanel);
            app.XSlider_3Label.Text = 'X';
            app.XSlider_3 = uislider(app.RotOnPostureSectionPanel);
            app.XSlider_3.Limits = [-180 180];
            app.XSlider_3.ValueChangedFcn = createCallbackFcn(app, @XSlider_3ValueChanged, true);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = '計算';
            app.AutoCalPanel = uipanel(app.CalculateTab);
            app.AutoCalPanel.Title = '周囲長計算・自動探索';
            app.CaluculationAutoBtn = uibutton(app.AutoCalPanel, 'push');
            app.CaluculationAutoBtn.ButtonPushedFcn = createCallbackFcn(app, @CaluculationAutoBtnButtonPushed, true);
            app.CaluculationAutoBtn.Text = '自動計算';
            app.Label_8 = uilabel(app.AutoCalPanel);
            app.Label_8.Text = 'サンプリング幅';
            app.SamplingWidth = uispinner(app.AutoCalPanel);
            app.SamplingWidth.Step = 0.5;
            app.SamplingWidth.Limits = [1 10];
            app.SamplingWidth.Value = 5;
            app.nSpinnerLabel = uilabel(app.AutoCalPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.AutoCalPanel);
            app.nSpinner.Limits = [0 10];
            app.nSpinner.Value = 3;
            app.WnSpinnerLabel = uilabel(app.AutoCalPanel);
            app.WnSpinnerLabel.Text = 'Wn';
            app.WnSpinner = uispinner(app.AutoCalPanel);
            app.WnSpinner.Limits = [0 1];
            app.WnSpinner.Value = 0.1;
            app.AutoOrManualCal = uibuttongroup(app.CalculateTab);
            app.AutoOrManualCal.SelectionChangedFcn = createCallbackFcn(app, @AutoOrManualCalSelectionChanged, true);
            app.AutoCal = uiradiobutton(app.AutoOrManualCal);
            app.AutoCal.Text = '自動';
            app.AutoCal.Value = true;
            app.ManualCal = uiradiobutton(app.AutoOrManualCal);
            app.ManualCal.Text = '一点指定';
            app.ROIselectionCal = uiradiobutton(app.AutoOrManualCal);
            app.ROIselectionCal.Text = '領域選択';
            app.resultApp = uipanel(app.CalculateTab);
            app.resultApp.Title = '結果';
            app.ToSaveDataTabBtn = uibutton(app.resultApp, 'push');
            app.ToSaveDataTabBtn.ButtonPushedFcn = createCallbackFcn(app, @ToSaveDataTabBtnButtonPushed, true);
            app.ToSaveDataTabBtn.Text = '保存タブへ';
            app.convAreaPanel = uipanel(app.resultApp);
            app.convAreaPanel.Title = '凸包断面積';
            app.Label_9 = uilabel(app.convAreaPanel);
            app.Label_9.Text = '推定周囲長';
            app.convCircumLength = uieditfield(app.convAreaPanel, 'numeric');
            app.Label_11 = uilabel(app.convAreaPanel);
            app.Label_11.Text = '校正済周囲長';
            app.calCircumMarkField = uieditfield(app.convAreaPanel, 'numeric');
            app.Label_12 = uilabel(app.convAreaPanel);
            app.Label_12.Text = '2階微分周囲長';
            app.Circum2Field = uieditfield(app.convAreaPanel, 'numeric');
            app.Label_13 = uilabel(app.convAreaPanel);
            app.Label_13.Text = '２階微分校正周囲長';
            app.withMarkCircum2Field = uieditfield(app.convAreaPanel, 'numeric');
            app.CircleFitRhoPanel = uipanel(app.resultApp);
            app.CircleFitRhoPanel.Title = '円近似半径';
            app.Label_20 = uilabel(app.CircleFitRhoPanel);
            app.Label_20.Text = '推定周囲長';
            app.rhoCircumLength = uieditfield(app.CircleFitRhoPanel, 'numeric');
            app.Label_21 = uilabel(app.CircleFitRhoPanel);
            app.Label_21.Text = '校正済周囲長';
            app.rhoCircumMarkField = uieditfield(app.CircleFitRhoPanel, 'numeric');
            app.Label_22 = uilabel(app.CircleFitRhoPanel);
            app.Label_22.Text = '2階微分周囲長';
            app.rhoCircum2Field = uieditfield(app.CircleFitRhoPanel, 'numeric');
            app.Label_23 = uilabel(app.CircleFitRhoPanel);
            app.Label_23.Text = '２階微分校正周囲長';
            app.rhowithMarkCircum2Field = uieditfield(app.CircleFitRhoPanel, 'numeric');
            app.boundRecAreaPanel = uipanel(app.resultApp);
            app.boundRecAreaPanel.Title = '外接矩形面積';
            app.Label_16 = uilabel(app.boundRecAreaPanel);
            app.Label_16.Text = '推定周囲長';
            app.recCircumLength = uieditfield(app.boundRecAreaPanel, 'numeric');
            app.Label_17 = uilabel(app.boundRecAreaPanel);
            app.Label_17.Text = '校正済周囲長';
            app.recCircumMarkField = uieditfield(app.boundRecAreaPanel, 'numeric');
            app.Label_18 = uilabel(app.boundRecAreaPanel);
            app.Label_18.Text = '2階微分周囲長';
            app.recCircum2Field = uieditfield(app.boundRecAreaPanel, 'numeric');
            app.Label_19 = uilabel(app.boundRecAreaPanel);
            app.Label_19.Text = '２階微分校正周囲長';
            app.recwithMarkCircum2Field = uieditfield(app.boundRecAreaPanel, 'numeric');
            app.CircumSearchTabGroup = uitabgroup(app.CalculateTab);
            app.csAreaTab = uitab(app.CircumSearchTabGroup);
            app.csAreaTab.Title = '断面積';
            app.GridLayout4 = uigridlayout(app.csAreaTab);
            app.UIAxes_csArea_diff2 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes_csArea_diff2, 'Position')
            ylabel(app.UIAxes_csArea_diff2, '断面積の二階微分')
            zlabel(app.UIAxes_csArea_diff2, 'Z')
            app.UIAxes_csArea_diff1 = uiaxes(app.GridLayout4);
            ylabel(app.UIAxes_csArea_diff1, '断面積の一階微分')
            zlabel(app.UIAxes_csArea_diff1, 'Z')
            app.UIAxes_csArea = uiaxes(app.GridLayout4);
            ylabel(app.UIAxes_csArea, '断面積')
            zlabel(app.UIAxes_csArea, 'Z')
            app.boundRectangleTab = uitab(app.CircumSearchTabGroup);
            app.boundRectangleTab.Title = '外接矩形';
            app.GridLayout5 = uigridlayout(app.boundRectangleTab);
            app.UIAxes_recArea_diff2 = uiaxes(app.GridLayout5);
            title(app.UIAxes_recArea_diff2, '外接矩形の二階微分')
            xlabel(app.UIAxes_recArea_diff2, 'Position')
            ylabel(app.UIAxes_recArea_diff2, '外接矩形の二階微分')
            zlabel(app.UIAxes_recArea_diff2, 'Z')
            app.UIAxes_recArea_diff1 = uiaxes(app.GridLayout5);
            ylabel(app.UIAxes_recArea_diff1, '外接矩形面積の一階微分')
            zlabel(app.UIAxes_recArea_diff1, 'Z')
            app.UIAxes_recArea = uiaxes(app.GridLayout5);
            ylabel(app.UIAxes_recArea, '外接矩形面積')
            zlabel(app.UIAxes_recArea, 'Z')
            app.CircleFitTab = uitab(app.CircumSearchTabGroup);
            app.CircleFitTab.Title = '円近似';
            app.GridLayout6 = uigridlayout(app.CircleFitTab);
            app.UIAxes_rho_diff2 = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes_rho_diff2, 'Position')
            ylabel(app.UIAxes_rho_diff2, '半径の二階微分')
            zlabel(app.UIAxes_rho_diff2, 'Z')
            app.UIAxes_rho_diff1 = uiaxes(app.GridLayout6);
            ylabel(app.UIAxes_rho_diff1, '半径の一階微分')
            zlabel(app.UIAxes_rho_diff1, 'Z')
            app.UIAxes_rho = uiaxes(app.GridLayout6);
            ylabel(app.UIAxes_rho, '円近似の半径')
            zlabel(app.UIAxes_rho, 'Z')
            app.TEditFieldLabel = uilabel(app.CalculateTab);
            app.TEditFieldLabel.Text = 'カット幅T';
            app.TEditField = uieditfield(app.CalculateTab, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 1;
            app.ManualCalPanel = uipanel(app.CalculateTab);
            app.ManualCalPanel.Title = '周囲長計算・位置指定';
            app.TabGroup2 = uitabgroup(app.ManualCalPanel);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '一点指定';
            app.OnePointSelection = uipanel(app.Tab);
            app.ReChooseBtn = uibutton(app.OnePointSelection, 'push');
            app.ReChooseBtn.ButtonPushedFcn = createCallbackFcn(app, @ReChooseBtnButtonPushed, true);
            app.ReChooseBtn.Text = '再選択';
            app.XEditFieldLabel = uilabel(app.OnePointSelection);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.YEditFieldLabel = uilabel(app.OnePointSelection);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.ZEditFieldLabel = uilabel(app.OnePointSelection);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.getPositionBtn = uibutton(app.OnePointSelection, 'push');
            app.getPositionBtn.ButtonPushedFcn = createCallbackFcn(app, @getPositionBtnButtonPushed, true);
            app.getPositionBtn.Text = '座標選択';
            app.CalculationManualy = uibutton(app.Tab, 'push');
            app.CalculationManualy.ButtonPushedFcn = createCallbackFcn(app, @CalculationManualyButtonPushed, true);
            app.CalculationManualy.Text = '計算';
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '領域探索';
            app.ROISelectionPanel = uipanel(app.Tab_2);
            app.ROISelectionPanel.Title = '範囲選択';
            app.ROIButton_2 = uibutton(app.ROISelectionPanel, 'push');
            app.ROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROIButton_2Pushed, true);
            app.ROIButton_2.Text = 'ROIセット';
            app.ClipingWithROI = uibutton(app.ROISelectionPanel, 'push');
            app.ClipingWithROI.ButtonPushedFcn = createCallbackFcn(app, @ClipingWithROIButtonPushed, true);
            app.ClipingWithROI.Text = '切り出し';
            app.ResetCalROIBtn = uibutton(app.ROISelectionPanel, 'push');
            app.ResetCalROIBtn.Text = 'Reset';
            app.CalculationManualy_2 = uibutton(app.Tab_2, 'push');
            app.CalculationManualy_2.Text = '計算';
            app.CalculationDirectionAxes = uibuttongroup(app.CalculateTab);
            app.CalculationDirectionAxes.Title = 'メイン計算方向軸';
            app.xButton_2 = uiradiobutton(app.CalculationDirectionAxes);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.CalculationDirectionAxes);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.CalculationDirectionAxes);
            app.zButton_2.Text = 'z';
            app.SaveDataTab = uitab(app.TabGroup);
            app.SaveDataTab.Title = '保存';
            app.dateMode = uibuttongroup(app.SaveDataTab);
            app.dateMode.Title = 'データの種類';
            app.DataRoi = uiradiobutton(app.dateMode);
            app.DataRoi.Text = '自動';
            app.DataRoi.Value = true;
            app.DataOnePoint = uiradiobutton(app.dateMode);
            app.DataOnePoint.Text = '一点指定';
            app.DataAuto = uiradiobutton(app.dateMode);
            app.DataAuto.Text = '領域選択';
            app.UITable = uitable(app.SaveDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label_24 = uilabel(app.SaveDataTab);
            app.Label_24.Text = '保存先';
            app.FileNameField = uieditfield(app.SaveDataTab, 'text');
            app.FileNameField.Placeholder = 'CircumData.xlsx';
            app.FileNameField.Value = 'convSrcData';
            app.FileNameField_2 = uieditfield(app.SaveDataTab, 'text');
            app.FileNameField_2.Placeholder = 'CircumData.xlsx';
            app.FileNameField_2.Value = 'boundRecSrcData';
            app.FileNameField_3 = uieditfield(app.SaveDataTab, 'text');
            app.FileNameField_3.Placeholder = 'CircumData.xlsx';
            app.FileNameField_3.Value = 'CircleRhoSrcData';
            app.Label_25 = uilabel(app.SaveDataTab);
            app.Label_25.Text = 'モデル名';
            app.ModelNameField = uieditfield(app.SaveDataTab, 'text');
            app.ModelNameField.Placeholder = '.stl';
            app.EditField_3Label = uilabel(app.SaveDataTab);
            app.EditField_3Label.Text = '日付';
            app.DateField = uieditfield(app.SaveDataTab, 'text');
            app.DateField.Placeholder = 'YYYY/MM/DD HH:MM:SS';
            app.CircleFitRhoPanel_2 = uipanel(app.SaveDataTab);
            app.CircleFitRhoPanel_2.Title = '円近似半径';
            app.Label_30 = uilabel(app.CircleFitRhoPanel_2);
            app.Label_30.Text = '推定周囲長';
            app.rhoCircumLength_2 = uieditfield(app.CircleFitRhoPanel_2, 'numeric');
            app.Label_31 = uilabel(app.CircleFitRhoPanel_2);
            app.Label_31.Text = '校正済周囲長';
            app.rhoCircumMarkField_2 = uieditfield(app.CircleFitRhoPanel_2, 'numeric');
            app.Label_32 = uilabel(app.CircleFitRhoPanel_2);
            app.Label_32.Text = '2階微分周囲長';
            app.rhoCircum2Field_2 = uieditfield(app.CircleFitRhoPanel_2, 'numeric');
            app.Label_33 = uilabel(app.CircleFitRhoPanel_2);
            app.Label_33.Text = '２階微分校正周囲長';
            app.rhowithMarkCircum2Field_2 = uieditfield(app.CircleFitRhoPanel_2, 'numeric');
            app.boundRecAreaPanel_2 = uipanel(app.SaveDataTab);
            app.boundRecAreaPanel_2.Title = '外接矩形面積';
            app.Label_34 = uilabel(app.boundRecAreaPanel_2);
            app.Label_34.Text = '推定周囲長';
            app.recCircumLength_2 = uieditfield(app.boundRecAreaPanel_2, 'numeric');
            app.Label_35 = uilabel(app.boundRecAreaPanel_2);
            app.Label_35.Text = '校正済周囲長';
            app.recCircumMarkField_2 = uieditfield(app.boundRecAreaPanel_2, 'numeric');
            app.Label_36 = uilabel(app.boundRecAreaPanel_2);
            app.Label_36.Text = '2階微分周囲長';
            app.recCircum2Field_2 = uieditfield(app.boundRecAreaPanel_2, 'numeric');
            app.Label_37 = uilabel(app.boundRecAreaPanel_2);
            app.Label_37.Text = '２階微分校正周囲長';
            app.recwithMarkCircum2Field_2 = uieditfield(app.boundRecAreaPanel_2, 'numeric');
            app.convAreaPanel_2 = uipanel(app.SaveDataTab);
            app.convAreaPanel_2.Title = '凸包断面積';
            app.Label_26 = uilabel(app.convAreaPanel_2);
            app.Label_26.Text = '推定周囲長';
            app.convCircumLength_2 = uieditfield(app.convAreaPanel_2, 'numeric');
            app.Label_27 = uilabel(app.convAreaPanel_2);
            app.Label_27.Text = '校正済周囲長';
            app.calCircumMarkField_2 = uieditfield(app.convAreaPanel_2, 'numeric');
            app.Label_28 = uilabel(app.convAreaPanel_2);
            app.Label_28.Text = '2階微分周囲長';
            app.Circum2Field_2 = uieditfield(app.convAreaPanel_2, 'numeric');
            app.Label_29 = uilabel(app.convAreaPanel_2);
            app.Label_29.Text = '２階微分校正周囲長';
            app.withMarkCircum2Field_2 = uieditfield(app.convAreaPanel_2, 'numeric');
            app.SaveBtn = uibutton(app.SaveDataTab, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnButtonPushed, true);
            app.SaveBtn.Text = '保存';
            app.Label_38 = uilabel(app.SaveDataTab);
            app.Label_38.Text = '校正倍率';
            app.CalibrationRateField = uieditfield(app.SaveDataTab, 'numeric');
            app.UITable_2 = uitable(app.SaveDataTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.SaveDataTab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.NextDataBtn = uibutton(app.SaveDataTab, 'push');
            app.NextDataBtn.ButtonPushedFcn = createCallbackFcn(app, @NextDataBtnPushed, true);
            app.NextDataBtn.Text = '次へ';
            app.Panel = uipanel(app.main);
            app.importButton = uibutton(app.Panel, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.importedfileField = uieditfield(app.Panel, 'text');
            app.importedfileField.Placeholder = 'filename';
            app.RebootButton = uibutton(app.main, 'push');
            app.RebootButton.ButtonPushedFcn = createCallbackFcn(app, @RebootButtonPushed, true);
            app.RebootButton.Text = 'Reboot';
            app.modeChangeGroup = uibuttongroup(app.main);
            app.modeChangeGroup.SelectionChangedFcn = createCallbackFcn(app, @modeChangeGroupSelectionChanged, true);
            app.Defaultmode = uiradiobutton(app.modeChangeGroup);
            app.Defaultmode.Text = '解析';
            app.Defaultmode.Value = true;
            app.Demomode = uiradiobutton(app.modeChangeGroup);
            app.Demomode.Text = 'デモ';
            app.CloseallButton = uibutton(app.main, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.hozonnButton = uibutton(app.main, 'push');
            app.hozonnButton.ButtonPushedFcn = createCallbackFcn(app, @hozonnButtonPushed, true);
            app.hozonnButton.Text = 'hozonn';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end